classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure               matlab.ui.Figure
        TabGroup               matlab.ui.container.TabGroup
        Device1Tab             matlab.ui.container.Tab
        CloseButton            matlab.ui.control.Button
        UIAxes2                matlab.ui.control.UIAxes
        DetailsButton          matlab.ui.control.Button
        HeadingIndicator       Aero.ui.control.HeadingIndicator
        ResetButton            matlab.ui.control.Button
        ShowPathButton         matlab.ui.control.Button
        SetLocationButton      matlab.ui.control.Button
        EditField              matlab.ui.control.EditField
        ConnectButton          matlab.ui.control.Button
        UITable                matlab.ui.control.Table
        UploadFloorplanButton  matlab.ui.control.Button
        UIAxes                 matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn2(app)


            addpath("C:\Users\HP\Desktop\IMU\sensor");
            addpath("C:\Users\HP\Desktop\IMU\sensor\quaternion_library");
            
            app.UITable.Data = ["Device 1", "Not Connected", "-", "-"];
            app.HeadingIndicator.Value = 145;
            
            
            app.TabGroup.Visible = 'off';
            
            app.UIAxes2.Title.String = 'Orientation';
            app.UIAxes2.XLim = [-1 1];
            app.UIAxes2.YLim = [-1 1];
            app.UIAxes2.ZLim = [-1 1];
            
            image = imread("C:\Users\HP\Desktop\IMU\sensor\floorplan2.png");

            grayimage = im2gray(image);
            bwimage = grayimage < 0.5;
            
            gridd = binaryOccupancyMap(bwimage,60);
            show(gridd, 'parent', app.UIAxes); 
            
            title(app.UIAxes, 'Inoor Map')
            
        end

        % Button pushed function: UploadFloorplanButton
        function UploadButtonPushed(app, event)
            [file,path] = uigetfile('*.png');
            image = imread(file);

            grayimage = im2gray(image);
            bwimage = grayimage < 0.5;
            
            gridd = binaryOccupancyMap(bwimage,60);
            show(gridd, 'parent', app.UIAxes); 
            
        end

        % Button pushed function: SetLocationButton
        function SetLocationButtonPushed(app, event)
            global x_loc;
            global y_loc;
            hold(app.UIAxes,'on');
            [x_loc, y_loc] = selectDataPoints(app, app.UIAxes);
            plot(app.UIAxes, x_loc, y_loc,'.','MarkerSize',30)
            hold(app.UIAxes,'off');
            
            function [x, y] = selectDataPoints(~, ax)
                roi = drawpoint(ax);
                x = roi.Position(1);
                y = roi.Position(2);
            end
        end

        % Button pushed function: ConnectButton
        function ConnectButtonPushed(app, event)

            global ahrs
            global myMQTT
            global mySub
            global stop 
            
            stop = 0;
            
            ahrs = MahonyAHRS('SamplePeriod', 1/50, 'Kp', 1);
            
            myMQTT = mqtt('tcp://192.168.0.144', 'Port', 1883);
        
            mySub = subscribe(myMQTT,'imu','Qos',0,'callback',@DataCollection);


            while 1
                pause(1)
                
                if stop == 1
                    clear global
                    clear DataCollection
                    clear ShowPathButtonPushed
                    break
                end
                
            end
            
            function DataCollection(topic,data)
        
                global rotated_data

                
                persistent n
                if isempty(n)
                    n = 1;
                end
        
                [ax ay az gx gy gz mx my mz temp] = strread(data, '%f %f %f %f %f %f %f %f %f %f','delimiter',',');
                
                if n == 1
                    app.UITable.Data = ["Device 1", "Connected & Not Calibrated", temp, "house"];
                end
                
                if n == 1000
                    app.UITable.Data = ["Device 1", "Connected & Calibrated", temp, "house"];
                end
                
                ahrs.Update([gx, gy, gz], [ax, ay, az], [mx, my, mz]);
        
                R = quatern2rotMat(ahrs.Quaternion)';
                
                disp("Yes")
              
                disp("No")
                
                %linear gravitation
%                 accg = inv(R)*([0;0;9.81]);
%                 axx = ax - accg(1);
%                 ayy = ay - accg(2);
%                 azz = az - accg(3);
%                 
%                 %centripetal
%                 accx = -gz*ayy*0.02 + gy*azz*0.02;
%                 accy = gz*axx*0.02 - gx*azz*0.02;
%                 accz = -gy*axx*0.02 + gx*ayy*0.02;

                accx = 0;
                accy = 0;
                accz = 0;
             
        
                %convert Acceleration to World Ref Frame
                aarot = R * [ax-accx, ay-accy, az-accz]';


                quat = quaternion([0,0,145*pi/180],'euler','XYZ','frame');
        
                arot = rotateframe(quat,[aarot(1),aarot(2),aarot(3)]);
                
                rotated_data(n,:) = [arot(1), arot(2), arot(3)];
                assignin('base','rotated_data',rotated_data);
                
                n = n + 1;
            end
        end

        % Button pushed function: ShowPathButton
        function ShowPathButtonPushed(app, event)
            
            global x_loc;
            global y_loc;
            
            global rotated_data;
            
            persistent last_n
            if isempty(last_n)
                last_n = 1000;
            end
            
            persistent num
            if isempty(num)
                num = 2;
            end
            
            persistent initial
            if isempty(initial)
                initial = [x_loc, y_loc];
            end
            
            v_old = [0 0];
            d_old = [0 0];
            
            sopmag = 0;
            spmag = 0;
            
            length_of_whole_data = length(rotated_data);
            
            disp(length_of_whole_data)
            
            subb = rotated_data(last_n:length_of_whole_data,1:2);
            
            disp(length(subb))
            
            [b, a] = butter(1, 0.005, 'high');
            sub = filtfilt(b, a, subb);
            
            for nn = 1:length(sub)
        
                soopmag = sopmag;
                sopmag = spmag;
                spmag = sqrt(sub(nn,1)*sub(nn,1) + sub(nn,2)*sub(nn,2));
        
                v_new = v_old + sub(nn,:) * 0.02;
        
                if abs(spmag) <0.15 && abs(sopmag)<0.15 && abs(soopmag) < 0.15
                    v_new = [0 0];
                end
        
                d_new = d_old + v_new * 0.02*2;
        
                v_old = v_new;
                d_old = d_new;
        
            end
        
            initial(num,:) = initial(num-1,:) + d_new;
            
            hold(app.UIAxes,'on');
            plot(app.UIAxes,initial(num-1:num,1),initial(num-1:num,2))
            hold(app.UIAxes,'off');
            
            num = num + 1;
            last_n = length_of_whole_data;

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            
            global myMQTT
            global mySub
            global stop 
            
            
            stop = 1;


            unsubscribe(mySub)
            unsubscribeAll(myMQTT)
            
            % Make current instance of app invisible
            app.UIFigure.Visible = 'off';
            
            
            % Open 2nd instance of app
            app1();  % <--------------The name of your app
            
            % Delete old instance
            close(app.UIFigure);
        end

        % Button pushed function: DetailsButton
        function DetailsButtonPushed(app, event)
            app.TabGroup.Visible = 'on';
            
        end

        % Button pushed function: CloseButton
        function CloseButtonPushed(app, event)
            app.TabGroup.Visible = 'off';
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Inoor Map')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UploadFloorplanButton = uibutton(app.UIFigure, 'push');
            app.UploadFloorplanButton.ButtonPushedFcn = createCallbackFcn(app, @UploadButtonPushed, true);
            app.UploadFloorplanButton.Text = 'Upload Floorplan';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Device'; 'Status'; 'Temperature'; 'Location'};
            app.UITable.RowName = {};
            app.ConnectButton = uibutton(app.UIFigure, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.ConnectButton.Text = 'Connect';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.Value = 'Indoor Navigation System';
            app.SetLocationButton = uibutton(app.UIFigure, 'push');
            app.SetLocationButton.ButtonPushedFcn = createCallbackFcn(app, @SetLocationButtonPushed, true);
            app.SetLocationButton.Text = 'Set Location';
            app.ShowPathButton = uibutton(app.UIFigure, 'push');
            app.ShowPathButton.ButtonPushedFcn = createCallbackFcn(app, @ShowPathButtonPushed, true);
            app.ShowPathButton.Text = 'Show Path';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.HeadingIndicator = uiaeroheading(app.UIFigure);
            app.DetailsButton = uibutton(app.UIFigure, 'push');
            app.DetailsButton.ButtonPushedFcn = createCallbackFcn(app, @DetailsButtonPushed, true);
            app.DetailsButton.Text = 'Details';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Device1Tab = uitab(app.TabGroup);
            app.Device1Tab.Title = 'Device 1';
            app.UIAxes2 = uiaxes(app.Device1Tab);
            title(app.UIAxes2, 'Orientation')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.CloseButton = uibutton(app.Device1Tab, 'push');
            app.CloseButton.ButtonPushedFcn = createCallbackFcn(app, @CloseButtonPushed, true);
            app.CloseButton.Text = 'Close';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn2)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end